classdef NeuroDominanceTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SamplingFrequencyEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        StartTimeofWindowofInterestminEditField  matlab.ui.control.NumericEditField
        StartTimeofWindowofInterestminEditFieldLabel  matlab.ui.control.Label
        PerformExtraAnalysisonSelectedEventButton  matlab.ui.control.Button
        PerformAnalysisButton        matlab.ui.control.Button
        UploadFileButton             matlab.ui.control.Button
        ExtractTablesDataButton      matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        DetectedSegments             matlab.ui.control.UIAxes
        ThetaDelta                   matlab.ui.control.UIAxes
        Spectrogram                  matlab.ui.control.UIAxes
        PowerSpectrum                matlab.ui.control.UIAxes
        DetectedThetaDominantEvents  matlab.ui.control.UIAxes
        OriginalSignal               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadFileButton
        function UploadFileButtonPushed(app, event)

            clc
            clear

            global data
            global Fs
            global recording_hip
            global time_recording
            global delta_range_org
            global theta_range_org
            global alpha_range_org
            global threshold_org
            global minDuration_org
            global filepath
            global filename
        
            % Default frequency band ranges (modifiable by user)
            delta_lower = 1;  % Enter the lower bound for the delta band
            delta_upper = 4;  % Enter the upper bound for the delta band
            
            theta_lower = 5;  % Enter the lower bound for the theta band
            theta_upper = 15; % Enter the upper bound for the theta band
            
            alpha_lower = 16; % Enter the lower bound for the alpha band
            alpha_upper = 19; % Enter the upper bound for the alpha band

            % Defining threshold and minimum segment duration
            threshold_org = 1.5;       %(modifiable by user)
            minDuration_org = 5; % in seconds (modifiable by user)
            
            % Apply scaling by multiplying the ranges by 10
            delta_range_org = delta_lower*10:delta_upper*10;  
            theta_range_org = theta_lower*10:theta_upper*10;  
            alpha_range_org = alpha_lower*10:alpha_upper*10;         
            
        
            [filename, path] = uigetfile({'*.abf', 'ABF Files (*.abf)'; '*.fif', 'FIF Files (*.fif)'; '*.edf', 'EDF Files (*.edf)'; '*.bdf', 'BDF Files (*.bdf)'; '*.*', 'All Files (*.*)'}, 'Select File');
        
            % Concatenate the path and filename
            filepath = fullfile(path, filename);
            
            if endsWith(filename, '.abf')
                % Load ABF file using abfload
                data = abfload(filepath);
                if isempty(data)
                    errordlg(['The ABF file "' filename '" is empty or could not be loaded.'], 'File Error');
                    return; % Exit the function if data is empty
                end
                recording_hip = data(:, 1); 
            elseif endsWith(filename, '.fif') || endsWith(filename, '.edf') || endsWith(filename, '.bdf')
                % Add FieldTrip to MATLAB path
                addpath(fullfile(path, 'fieldtrip-master')); % Update with your actual path
                ft_defaults; % Set FieldTrip defaults
                
                % Specify the dataset
                cfg = [];
                cfg.dataset = filepath;     
                cfg.channel = {'all'}; 
                cfg.hpfilter = 'yes';
                cfg.hpfreq = 2;
                cfg.hpfiltord = 2; % Order of the filter
                cfg.hpfilttype = 'but'; 
                cfg.lpfilter = 'yes';
                cfg.lpfreq = 20;
                cfg.lpfiltord = 2; % Order of the filter
                cfg.lpfilttype = 'but'; 
                
                % Check if filter frequencies are valid before processing
                try
                    Fn = Fs / 2; % Nyquist frequency
                    Flp = cfg.lpfreq; % Low-pass filter frequency
                    if Flp <= 0 || Flp >= Fn
                        errordlg('Invalid low-pass filter frequency. The frequency must be between 0 and the Nyquist frequency.', 'Filter Frequency Error');
                        return; % Exit the function if the filter frequency is invalid
                    end
                catch
                    errordlg('Error in filter configuration. Please check the filter parameters.', 'Filter Configuration Error');
                    return; % Exit if there's a configuration issue
                end
                
                % Perform preprocessing with the checked filter parameters
                try
                    data_continuous = ft_preprocessing(cfg);
                    if isempty(data_continuous)
                        errordlg(['The FieldTrip preprocessing of the "' filename '" was failed.'], 'File Error');
                        return; % Exit the function if data is empty
                    end
                catch
                    errordlg('Error during data preprocessing. Please check the FieldTrip settings or the data file.', 'Preprocessing Error');
                    return; % Exit if preprocessing fails
                end
                
                % Down sampling
                cfg = [];
                cfg.resamplefs = Fs;
                data_downsampled = ft_resampledata(cfg, data_continuous);
                
                if isempty(data_downsampled)
                    errordlg(['The FieldTrip resampling of the "' filename '" was failed.'], 'File Error');
                    return; % Exit the function if downsampled data is empty
                end
        
                % Extract data from the first trial
                data = data_downsampled.trial{1, 1};
                if isempty(data)
                    errordlg(['FieldTrip trial extraction of the "' filename '" was failed.'], 'Data Error');
                    return; % Exit the function if trial data is empty
                end
        
                % Get the size of the data matrix
                [numRows, numCols] = size(data);
                if numRows == 0 || numCols == 0
                    errordlg(['The data from "' filename '" is empty. The file may be corrupted or not contain data.'], 'Data Error');
                    return; % Exit the function if data matrix is empty
                end
        
                % Compare the number of rows and columns
                if numRows > numCols % If there are more rows than columns
                    recording_hip = data(:, 1);
                    nSamples = length(recording_hip);
                    % Create a time vector
                    time_recording = (1:1:nSamples) ./ (Fs*60);
                    time_recording = time_recording';
                else
                    % If there are less rows than columns, transpose the matrix
                    data = data.'; % Transpose the matrix
                    recording_hip = data(:, 1)';
                    nSamples = length(recording_hip);
                    % Create a time vector
                    time_recording = (1:1:nSamples) ./ (Fs*60);                            
                end
            else
                errordlg('Unsupported file format. Please select a .abf, .fif, .edf, or .bdf file.', 'File Format Error');
                return; % Exit the function if unsupported file format
            end
        
            % Use the loaded data as needed
            % ...
        
            tic;
                
                
        end

        % Callback function
        function AnalysisSettingButtonPushed(app, event)
            % Create a dialog box for parameter entry
            dlgTitle = 'Parameter Entry';
            prompt = {'Sampling Frequency:', 'Parameter 2:', 'Parameter 3:'};
            numLines = 1;
            defaultParams = {'', '', ''};
            enteredParams = inputdlg(prompt, dlgTitle, numLines, defaultParams);
        
            % Check if the user clicked 'Cancel' or closed the dialog
            if isempty(enteredParams)
                return; % Exit the function without further processing
            end
        
            % Retrieve the entered parameter values
            global Fs
            Fs = str2double(enteredParams{1});
            parameter2Value = enteredParams{2};
            parameter3Value = enteredParams{3};
        
            % Perform any necessary actions with the parameter values
            disp('Entered Parameter Values:');
            disp(['Sampling Frequency: ', Fs]);
            disp(['Parameter 2: ', parameter2Value]);
            disp(['Parameter 3: ', parameter3Value]);       

        end

        % Callback function: PerformAnalysisButton, UITable, UITable,
        % UITable
        function PerformAnalysisButtonPushed(app, event)


            tic;
            % Loading data
            global filepath
            global filename
            global recording_hip
            global Fs
            if endsWith(filename, '.abf')
                % Load ABF file using abfload
                data = abfload(filepath);
                recording_hip = data(:,1);
            end
            %recording_hip = data(:,1); 
            % Get the number of samples in the signal
            nSamples = length(recording_hip);
            % Create a time vector
            time_recording = (1:1:nSamples) ./ (Fs*60);
            window_size = 60*Fs; % Window size in samples
            % Create sliding windows
            n_windows = floor(numel(recording_hip)/window_size) - 1;
            windows = zeros(window_size, n_windows);          
            

            % Initialize variables and constants
            final_results = zeros(0, 9);  % Initialize as an empty matrix with 10 columns
            theta2delta_ratio_total = [];
            time_total = [];
            
            % Loop through windows
            for num_window = 1:n_windows
                disp(['Time (min): ' num2str(num_window-1)]);

                selected_window = recording_hip(1+(num_window-1)*window_size:num_window*window_size);   
            
                % Call function to get theta dominant segments
                [time_0, segments_index, theta2delta_ratio] = thetaDominantSegments_based_on_ratio_whole_recording(app, selected_window, Fs, num_window);
                time2 = 1/Fs:1/Fs:numel(selected_window)/Fs;
                time3 = 1/Fs:1/Fs:numel(theta2delta_ratio)/Fs;
                time_total = [time_total, (time3 + 60*(num_window-1))/60];
                theta2delta_ratio_total = [theta2delta_ratio_total, theta2delta_ratio];
            
                [num_segments, ~] = size(segments_index);
            
                % Process each segment
                if isempty(segments_index)
                    % Do nothing
                else
                    for segmentIdx = 1:num_segments
                        segmentStart = segments_index(segmentIdx, 1);
                        segmentEnd = segments_index(segmentIdx, 2);
            
                        % Find indices in time2 array
                        index_start = find(time2 <= 1.01 * time_0(segmentStart));
                        index_start = index_start(end);
                        index_end = find(time2 <= 1.01 * time_0(segmentEnd));
                        index_end = index_end(end);
            
                        % Analyze power spectrum
                        [Peak_Amplitude, Peak_frequency, Bandwidth] = analyzePowerSpectrum_3(app, time2(index_start:index_end), Fs, selected_window(index_start:index_end));

                        new_row = [];

                        if numel(Peak_Amplitude) == 1;
                            new_row = [(time2(index_start) + 60*(num_window-1))/60, (time2(index_end) + 60*(num_window-1))/60, numel(Peak_Amplitude), Peak_Amplitude(1), Peak_frequency(1), Bandwidth(1), NaN, NaN, NaN];                          
                            
                        else numel(Peak_Amplitude) > 1;
                            new_row = [(time2(index_start) + 60*(num_window-1))/60, (time2(index_end) + 60*(num_window-1))/60, numel(Peak_Amplitude), Peak_Amplitude(1), Peak_frequency(1), Bandwidth(1), Peak_Amplitude(2), Peak_frequency(2), Bandwidth(2)];
                        end
                        final_results = [final_results; new_row];
            
            
                    end
                end
            end
            
            % Remove rows where all elements are zero
            rows_to_remove = all(final_results == 0, 2);            
            final_results(rows_to_remove, :) = [];
            % Stop the timer
            elapsedTime = toc;
            % Convert the results to a table
            result_table = final_results;
            % Find rows where all elements are zero
            rows_to_delete = sum(result_table, 2) == 0;
            % Delete rows
            result_table(rows_to_delete, :) = [];
            
            % Display the results in the UITable
            columnNames = {'Start Time (min)', 'End Time (min)', ...
                'Number Of Peaks In Power Spectrum', ...
                'First Peak Amp In Power Spectrum', 'Frequency Of First Peak Amp In Power Spectrum Hz', 'Bandwidth Of First Peak In Power Spectrum Hz', ...
                'Second Peak Amp In Power Spectrum', 'Frequency Of Second Peak Amp In Power Spectrum Hz', 'Bandwidth Of Second Peak In Power Spectrum Hz'};
            
            app.UITable.ColumnName = columnNames;
            app.UITable.Data = result_table;
            
            ind_time = min(find(time_recording>=max(time_total)));
            
            plot(app.OriginalSignal,time_recording(1:ind_time), recording_hip(1:ind_time), 'k')
            xlim(app.DetectedThetaDominantEvents,[0 max(time_recording(1:ind_time))])
            xlabel(app.DetectedThetaDominantEvents,'Time (min)')
            ylabel(app.DetectedThetaDominantEvents,'Amp (µV)')
            
            plot(app.DetectedThetaDominantEvents,time_total, theta2delta_ratio_total, 'k')
            xlim(app.DetectedThetaDominantEvents,[0 max(time_recording(1:ind_time))])   %max(time_total)
            xlabel(app.DetectedThetaDominantEvents,'Time (min)')
            ylabel(app.DetectedThetaDominantEvents,'  ')
        end
        
            
            % -------------------------------- functions
            function [time_0,segments_index,theta2delta_ratio] = thetaDominantSegments_based_on_ratio_whole_recording(app,signal,Fs,num_window)
        
            % Computing the spectrogram
            [S, F, T, P] = spectrogram(signal, round(Fs * 0.9), round(Fs * 0.8), 1:0.1:20, Fs);
        
            % Computing the ratio of theta to delta
            global delta_range_org
            global theta_range_org
            global alpha_range_org
            delta = sum(P(delta_range_org, :)) ./ sum(P);
            theta = sum(P(theta_range_org, :)) ./ sum(P);
            alpha = sum(P(alpha_range_org, :)) ./ sum(P);
        
        
            % Defining threshold and minimum segment duration
            global minDuration_org
            global threshold_org
            threshold = threshold_org;
            minSegmentDuration = 10*minDuration_org; % in seconds
        
            % Compute theta_delta_ratio
            theta2delta_ratio = theta ./ delta ;
            theta2alpha_ratio = theta ./ alpha ;
        
            % Identify segments where ratio exceeds the threshold
            aboveThreshold = (theta2delta_ratio > threshold) & (theta2alpha_ratio > threshold);
        
            % Find indices where segments start and end
            startIndices = find(diff([0 aboveThreshold]) == 1);
            endIndices = find(diff([aboveThreshold 0]) == -1);
        
            % Initialize array to store segment information
            numSegments = length(startIndices);
            segments = zeros(numSegments, 4);
        
            % Compute segment characteristics and store in the array
            for i = 1:numSegments
                startIdx = startIndices(i);
                endIdx = endIndices(i);
                duration = (endIdx - startIdx + 1);
                area = trapz(theta2delta_ratio(startIdx:endIdx) - threshold);
        
                segments(i, 1) = startIdx;
                segments(i, 2) = endIdx;
                segments(i, 3) = duration;
                segments(i, 4) = area;
            end
        
            % Remove segments with duration less than minSegmentDuration
            [row,col] = find(segments(:, 3) >= minSegmentDuration);
            numSegments_2 = row;
            segments = segments(segments(:, 3) >= minSegmentDuration, :);
        
            % Plot signals and detected segments
            time = T + num_window*60;
            time_0 = T ; %+ num_window*60;
            
                          
                if numel(nonzeros(segments))>0
        
                % Highlight detected segments
                for ij = 1:numel(numSegments_2)
                    segmentStart = startIndices(numSegments_2(ij));
                    segmentEnd = endIndices(numSegments_2(ij));
               
                    plot(app.DetectedThetaDominantEvents,[time(segmentStart), time(segmentEnd)], [threshold, threshold], 'm', 'LineWidth', 2);
                    fill(app.DetectedThetaDominantEvents,time(segmentStart:segmentEnd), theta2delta_ratio(segmentStart:segmentEnd), 'g', 'FaceAlpha', 0.3);
        
                end
                 
                
                % Update the figure window
                drawnow;                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                                        
        
                % Display the resulting array
                disp('Presence of a Dominant Mid-Frequency Episode')
                
                segments_index = segments;
                else
                    segments_index = [];
                end
            end
            
            % -------------------------------- functions
            
            % -------------------------------- functions
            function [Peak_Amplitude, Peak_frequency, Bandwidth] = analyzePowerSpectrum_3(app,time,sampling_freq,signal)
        
            % Set frequency and sample rate
            Fs = sampling_freq;
            t1 = time;


            N    = 800;      % Order
            Fc1  = 4;        % First Cutoff Frequency
            Fc2  = 13;        % Second Cutoff Frequency
            flag = 'scale';  % Sampling Flag
            % Create the window vector for the design algorithm.
            win = hamming(N+1);

            % Calculate the coefficients using the FIR1 function.
            b2  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            y2=filter(b2,1,signal);

            y = y2;


            % Map the input vector if it is a matrix
            y = y(:).';
            L = length(y);

            % Compute FFT using 2^N points
            N = ceil(log2(length(y)));
            fy = fft(y, 2^N) / (L / 2);

            % Amplitude adjustment for complex input y
            if isreal(y) == 0
                fy = fy / 2;
            end

            % Compute power spectral density (PSD)
            power = fy .* conj(fy);
            phy = angle(fy); % Phase angle

            % Compute frequency axis
            f = (Fs / 2^N) * (0:2^(N-1) - 1);

            % Compute moving average of power spectrum
            power_spectrum = movmean(power(1:2^(N-1)), 5);        

            frequencies = f(20:350)';
            power_1to30 = power_spectrum(20:350)';

            % Fit Gaussian model to the filtered data
            gaussianModel = fit(frequencies, power_1to30, 'gauss2');
            a1 = gaussianModel.a1;
            b1 = gaussianModel.b1;
            c1 = gaussianModel.c1;
            a2 = gaussianModel.a2;
            b2 = gaussianModel.b2;
            c2 = gaussianModel.c2;
            
            % Create Gaussian model
            gaussian_Model = a1 * exp(-((frequencies - b1) / c1).^2) + a2 * exp(-((frequencies - b2) / c2).^2);
            
            % Extract the output parameters
            [Peak_Amplitude, Peak_frequency, Bandwidth, proms] = findpeaks(gaussian_Model, frequencies, 'Annotate', 'extents', 'WidthReference', 'halfheight');
            for num_f = 1:numel(Peak_frequency)
                f1(num_f) = max(find(frequencies <= Peak_frequency(num_f)));
            end
            Peak_Amplitude = power_1to30(f1);     
            
            
            % ---------------------------- functions
            
        end

        % Button pushed function: PerformExtraAnalysisonSelectedEventButton
        function PerformExtraAnalysisonSelectedEventButtonPushed(app, event)
            global detected_window_num
            global data
            global Fs
            target_time = 60*(detected_window_num-1);
            recording_hip = data(:,1); 
            % Get the number of samples in the signal
            nSamples = length(recording_hip);
            % Create a time vector
            time_recording = (1:1:nSamples) ./ (Fs*60);
            window_size = 60*Fs; % Window size in samples
            % Create sliding windows
            n_windows = floor(numel(recording_hip)/window_size) - 1;
            windows = zeros(window_size, n_windows);
            
            for i = detected_window_num
            selected_window = recording_hip(1+(i-1)*window_size:i*window_size);   
            
            [time_0,segments_index,theta2delta_ratio] = thetaDominantSegments_based_on_ratio(app,selected_window,Fs,target_time);
            time = 1/Fs:1/Fs:numel(selected_window)/Fs;
            
        
            if isempty(segments_index)
                %do nothing
            else
                segmentStart = segments_index(1, 1);
                segmentEnd = segments_index(1, 2);
        
                index_start = find(time<=1.01*time_0(segmentStart));
                index_start = index_start(end);
                index_end = find(time<=1.01*time_0(segmentEnd));
                index_end = index_end(end);
        
                analyzePowerSpectrum_2(app,time(index_start:index_end),Fs,selected_window(index_start:index_end));

            end
        
            end
        end
        
        function [Peak_Amplitude, Peak_frequency, Bandwidth] = analyzePowerSpectrum_2(app,time,sampling_freq,signal)

            global Fs

            % Set frequency and sample rate
            Fs = sampling_freq;
            t1 = time;


            N    = 800;      % Order
            Fc1  = 4;        % First Cutoff Frequency
            Fc2  = 13;        % Second Cutoff Frequency
            flag = 'scale';  % Sampling Flag
            % Create the window vector for the design algorithm.
            win = hamming(N+1);

            % Calculate the coefficients using the FIR1 function.
            b2  = fir1(N, [Fc1 Fc2]/(Fs/2), 'bandpass', win, flag);
            y2=filter(b2,1,signal);

            y = y2;


            % Map the input vector if it is a matrix
            y = y(:).';
            L = length(y);

            % Compute FFT using 2^N points
            N = ceil(log2(length(y)));
            fy = fft(y, 2^N) / (L / 2);

            % Amplitude adjustment for complex input y
            if isreal(y) == 0
                fy = fy / 2;
            end

            % Compute power spectral density (PSD)
            power = fy .* conj(fy);
            phy = angle(fy); % Phase angle

            % Compute frequency axis
            f = (Fs / 2^N) * (0:2^(N-1) - 1);

            % Compute moving average of power spectrum
            power_spectrum_1 = movmean(power(1:2^(N-1)), 5); 

            
            % Define the signal and sampling frequency
            theta_seg = signal;
            
            
            % Define the time vector
            t = time;
            
            % Compute the FFT of the signal
            N = length(theta_seg);
            Y = fft(theta_seg);
            
            % Compute the two-sided spectrum and then the single-sided spectrum
            P2 = abs(Y/N);
            P1 = P2(1:N/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            
            % Define the frequency domain
            f = Fs*(0:(N/2))/N;
            
            % Smooth the power spectrum using a moving average filter
            window_size = 3; % Adjust the window size for more or less smoothing
            P1_smooth = movmean(P1, window_size);
            frequencies = f';
            power_1to30 = P1_smooth;


           % Fit Gaussian model
            % Define frequency range
            freq_range = frequencies >= 1 & frequencies <= 30;
            
            % Filter data within the range 1-30 Hz
            frequencies_1to30 = frequencies(freq_range);
            power_1to30_filtered = power_1to30(freq_range);
            max_power_1to30_filtered = max(power_1to30_filtered);
            max_power_spectrum_1 = max(power_spectrum_1);
            normalized_power = power_1to30_filtered.*(max_power_spectrum_1./max_power_1to30_filtered);
            
            % Clear the existing plot in the axes
            cla(app.PowerSpectrum);

            % Plot power spectrum
            plot(app.PowerSpectrum, frequencies_1to30, normalized_power, 'k');
            % Set x-axis limits and labels
            legend(app.PowerSpectrum, 'off');
            xlim(app.PowerSpectrum, [1 30]);
            xlabel(app.PowerSpectrum, 'Freq (Hz)');
            ylabel(app.PowerSpectrum, 'Power');
            title(app.PowerSpectrum, 'Power Spectrum of Detected Segments');

        
        end
            
            function [time_0,segments_index,theta2delta_ratio] = thetaDominantSegments_based_on_ratio(app,signal,Fs,target_time)

                % Computing the spectrogram
                [S, F, T, P] = spectrogram(signal, round(Fs * 0.9), round(Fs * 0.8), 1:0.1:20, Fs);
            
                % Computing the ratio of theta to delta
                global delta_range_org
                global theta_range_org
                global alpha_range_org
                delta = sum(P(delta_range_org, :)) ./ sum(P);
                theta = sum(P(theta_range_org, :)) ./ sum(P);
                alpha = sum(P(alpha_range_org, :)) ./ sum(P);
            
            
                % Defining threshold and minimum segment duration
                global minDuration_org
                global threshold_org
                threshold = threshold_org;
                minSegmentDuration = 10*minDuration_org; % in seconds
            
                % Compute theta_delta_ratio
                theta2delta_ratio = theta ./ delta ;
                theta2alpha_ratio = theta ./ alpha ;
            
                % Identify segments where ratio exceeds the threshold
                aboveThreshold = (theta2delta_ratio > threshold) & (theta2alpha_ratio > threshold);
            
                % Find indices where segments start and end
                startIndices = find(diff([0 aboveThreshold]) == 1);
                endIndices = find(diff([aboveThreshold 0]) == -1);
            
                % Initialize array to store segment information
                numSegments = length(startIndices);
                segments = zeros(numSegments, 4);
            
                % Compute segment characteristics and store in the array
                for i = 1:numSegments
                    startIdx = startIndices(i);
                    endIdx = endIndices(i);
                    duration = (endIdx - startIdx + 1);
                    area = trapz(theta2delta_ratio(startIdx:endIdx) - threshold);
            
                    segments(i, 1) = startIdx;
                    segments(i, 2) = endIdx;
                    segments(i, 3) = duration;
                    segments(i, 4) = area;
                end
            
                % Remove segments with duration less than minSegmentDuration
                [row,col] = find(segments(:, 3) >= minSegmentDuration);
                numSegments_2 = row;
                segments = segments(segments(:, 3) >= minSegmentDuration, :);
            
                % Plot signals and detected segments
                time = T;
                time_0 = T;
                
                
                    imagesc(app.Spectrogram,(T+ target_time)./60, F, 10*log10(P));
                    set(app.Spectrogram, 'YDir', 'normal');
                    min_min = max(max(10*log10(P)))-40; %3*max(max(10*log10(P)));
                    max_max = max(max(10*log10(P))); %1*max(max(10*log10(P)));
                    caxis(app.Spectrogram, [min_min max_max])
                    xlabel(app.Spectrogram, 'Time (min)');
                    ylabel(app.Spectrogram, 'Freq (Hz)');
                    % Add the color bar
                    cb = colorbar(app.Spectrogram);
                    % Set the width of the color bar (adjust the value as needed)
                    %cb.Position(3) = cb.Position(3) * 0.5; % Example: Make the color bar half as wide
                    % Add a title to the color bar
                    title(cb, '10*log10(P)');
                    
                    
                    % Assuming app.ThetaDelta is the handle to the figure
                    % Clear the existing plot in the axes
                    cla(app.ThetaDelta);
                    % Plot theta in blue
                    plot(app.ThetaDelta, (time + target_time)./60, theta, 'k');
                    hold(app.ThetaDelta, 'on');
                    % Plot delta in red
                    plot(app.ThetaDelta, (time + target_time)./60, delta, 'b');
                    hold(app.ThetaDelta, 'on');
                    % Plot delta in red
                    plot(app.ThetaDelta, (time + target_time)./60, alpha, 'Color', [0, 0.5, 0]);
                    % Set labels and title for the plot
                    xlabel(app.ThetaDelta, 'Time (min)');
                    ylabel(app.ThetaDelta, 'Normalised Power');
                    title(app.ThetaDelta, 'Normalised Power of Frequency Bands');
                    % Add a legend to differentiate between theta and delta
                    legend(app.ThetaDelta, '5-15 Hz', '1-4 Hz', '16-19 Hz');
                    hold(app.ThetaDelta, 'off');

                    plot(app.DetectedSegments, (time + target_time)./60, theta2delta_ratio, 'k');
                    hold(app.DetectedSegments, 'on');
                    plot(app.DetectedSegments, (time + target_time)./60, ones(1, numel(theta)) * threshold, 'r--');
                    xlabel(app.DetectedSegments,'Time (min)');
                    ylabel(app.DetectedSegments,'Mid to Low Ratio');
                    title(app.DetectedSegments,'Mid to Low Ratio (5-15 Hz to 1-4 Hz) and Detected Segments');

% ---------------------------------
                    if numel(nonzeros(segments))>0
            
                    % Highlight detected segments
                    for ij = 1:numel(numSegments_2)
                        segmentStart = startIndices(numSegments_2(ij));
                        segmentEnd = endIndices(numSegments_2(ij));
                    
                        % Plot the threshold line
                        plot(app.DetectedSegments, [(time(segmentStart) + target_time) ./ 60, (time(segmentEnd) + target_time) ./ 60], [threshold, threshold], 'm', 'LineWidth', 2);
                        
                        % Prepare vertices for filling
                        x_vertices = (time(segmentStart:segmentEnd) + target_time) ./ 60; % Convert time to minutes
                        y_vertices = theta2delta_ratio(segmentStart:segmentEnd);

                        
                        % Ensure the vertices are in the correct order to form a closed polygon
                        x_fill = [x_vertices, fliplr(x_vertices)]; % Add end and start points to close the polygon
                        y_fill = [y_vertices, threshold * ones(size(y_vertices))]; % Add threshold values to close the polygon
                        
                        % Fill the area under the curve between the vertices
                        fill(app.DetectedSegments, x_fill, y_fill, 'g', 'FaceAlpha', 0.3);
                    
                    end
                    
                    hold(app.DetectedSegments, 'off'); % Release hold on the plot               
                     
                                
                    segments_index = segments;
                    else
                        segments_index = [];
                    end
% --------------------------------- 
            

        end

        % Value changed function: StartTimeofWindowofInterestminEditField
        function StartTimeofWindowofInterestminEditFieldValueChanged(app, event)
        global detected_window_num
            detected_window_num = 1 + app.StartTimeofWindowofInterestminEditField.Value;
            
        end

        % Button pushed function: ExtractTablesDataButton
        function ExtractTablesDataButtonPushed(app, event)

                % Get the data from the UITable
                data = app.UITable.Data;
                
                % Open a dialog to select the save directory
                saveDir = uigetdir('Select Save Directory');
                if isequal(saveDir, 0)
                    % User canceled the dialog
                    return;
                end
                
                % Create a file name
                filename = 'Characteristics of Mid-Frequency Dominant Episodes.xlsx';
                
                % Create a full file path
                filepath = fullfile(saveDir, filename);
                
                % Define your column names
                columnNames = {'Start Time (min)', 'End Time (min)', ...
                    'Number Of Peaks In Power Spectrum', ...
                    'First Peak Amp In Power Spectrum', 'Frequency Of First Peak Amp In Power Spectrum Hz', 'Bandwidth Of First Peak In Power Spectrum Hz', ...
                    'Second Peak Amp In Power Spectrum', 'Frequency Of Second Peak Amp In Power Spectrum Hz', 'Bandwidth Of Second Peak In Power Spectrum Hz'};
                
                % Check if data is a matrix and has the correct number of columns
                if ismatrix(data) && size(data, 2) == numel(columnNames)
                    % Create the table from the matrix
                    dataTable = array2table(data, 'VariableNames', columnNames);
                
                    % Write the data to an Excel file
                    try
                        writetable(dataTable, filepath, 'Sheet', 'Sheet1', 'WriteVariableNames', true);
                        disp('Excel file saved successfully.');
                    catch exception
                        disp(['Error saving Excel file: ' exception.message]);
                    end
                else
                    disp('Error: Data must be a matrix with the same number of columns as the column names.');
                end
            

        end

        % Value changed function: SamplingFrequencyEditField
        function SamplingFrequencyEditFieldValueChanged(app, event)
           global Fs
        
            Fs = app.SamplingFrequencyEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OriginalSignal = uiaxes(app.UIFigure);
            title(app.OriginalSignal, 'Original Signal (Entire Recording)')
            xlabel(app.OriginalSignal, 'Time (min)')
            ylabel(app.OriginalSignal, 'Amp (mV)')
            app.DetectedThetaDominantEvents = uiaxes(app.UIFigure);
            title(app.DetectedThetaDominantEvents, 'Detecting Mid- to Low-Freq Ratio Peaks Across the Entire Recording')
            xlabel(app.DetectedThetaDominantEvents, 'Time (min)')
            ylabel(app.DetectedThetaDominantEvents, 'Ratio Peak')
            app.PowerSpectrum = uiaxes(app.UIFigure);
            title(app.PowerSpectrum, 'Power Spectrum of Detected Segments')
            xlabel(app.PowerSpectrum, 'Freq (Hz)')
            ylabel(app.PowerSpectrum, 'PSD')
            app.Spectrogram = uiaxes(app.UIFigure);
            title(app.Spectrogram, 'Spectrogram')
            xlabel(app.Spectrogram, 'Time (min)')
            ylabel(app.Spectrogram, 'Freq (Hz)')
            app.ThetaDelta = uiaxes(app.UIFigure);
            title(app.ThetaDelta, 'Normalised Power of Frequency Bands')
            xlabel(app.ThetaDelta, 'Time (min)')
            ylabel(app.ThetaDelta, 'Normalised Power')
            app.DetectedSegments = uiaxes(app.UIFigure);
            title(app.DetectedSegments, 'Mid to Low Ratio (5-15 Hz to 1-4 Hz) and Detected Segments')
            xlabel(app.DetectedSegments, 'Time (min)')
            ylabel(app.DetectedSegments, 'Mid to Low Ratio ')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start Time (min)'; 'End Time (min)'; 'Number Of Peaks In Power Spectrum'; 'First Peak Amp In Power Spectrum'; 'Frequency Of First Peak Amp In Power Spectrum Hz'; 'Bandwidth Of First Peak In Power Spectrum Hz'; 'Second Peak Amp In Power Spectrum'; 'Frequency Of Second Peak Amp In Power Spectrum Hz'; 'Bandwidth Of Second Peak In Power Spectrum Hz'; ''};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.ExtractTablesDataButton = uibutton(app.UIFigure, 'push');
            app.ExtractTablesDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractTablesDataButtonPushed, true);
            app.ExtractTablesDataButton.Text = 'Extract Table''s Data';
            app.UploadFileButton = uibutton(app.UIFigure, 'push');
            app.UploadFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFileButtonPushed, true);
            app.UploadFileButton.Text = 'Upload File';
            app.PerformAnalysisButton = uibutton(app.UIFigure, 'push');
            app.PerformAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerformAnalysisButtonPushed, true);
            app.PerformAnalysisButton.Text = 'Perform Analysis';
            app.PerformExtraAnalysisonSelectedEventButton = uibutton(app.UIFigure, 'push');
            app.PerformExtraAnalysisonSelectedEventButton.ButtonPushedFcn = createCallbackFcn(app, @PerformExtraAnalysisonSelectedEventButtonPushed, true);
            app.PerformExtraAnalysisonSelectedEventButton.Text = 'Perform Extra Analysis on Selected Event';
            app.StartTimeofWindowofInterestminEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeofWindowofInterestminEditFieldLabel.Text = 'Start Time of Window of Interest (min)';
            app.StartTimeofWindowofInterestminEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeofWindowofInterestminEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeofWindowofInterestminEditFieldValueChanged, true);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencyEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NeuroDominanceTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end